classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ResetAllButton                  matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        ResetCalAutoButton              matlab.ui.control.Button
        ButtonGroup                     matlab.ui.container.ButtonGroup
        zButton                         matlab.ui.control.RadioButton
        yButton                         matlab.ui.control.RadioButton
        xButton                         matlab.ui.control.RadioButton
        TEditField                      matlab.ui.control.NumericEditField
        TEditFieldLabel                 matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        PostureControlPanel             matlab.ui.container.Panel
        OKButton                        matlab.ui.control.Button
        ResetPostureButton              matlab.ui.control.Button
        TrimerangeDown                  matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        TrimerangeUp                    matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        TrimDirectionSelectButtonGroup  matlab.ui.container.ButtonGroup
        zButton_2                       matlab.ui.control.RadioButton
        yButton_2                       matlab.ui.control.RadioButton
        xButton_2                       matlab.ui.control.RadioButton
        Button                          matlab.ui.control.Button
        PCATrimmerAppButton             matlab.ui.control.Button
        filenameField                   matlab.ui.control.EditField
        ImportButton                    matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ptCloud % Original Data
        importedfig % 
        newPoint    %　位置合わせ中の点群データ変数
        fixedPoint % 位置合わせ完了した時の点群データ
        secondFig   %　
        cutWidth      %　切る幅
        cutAxes
        convexMatrix % 回転行列
        trimAxes
        trimerrangeUp
        trimerrangeDown
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;
            app.cutWidth = app.TEditField.Value;
            app.trimAxes = app.TrimDirectionSelectButtonGroup.SelectedObject.Text;
            app.cutAxes = app.ButtonGroup.SelectedObject.Text;
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            filename = uigetfile({'*.stl';'*.ply'});
            DoShow = false;

            app.ptCloud = import3Dmodel(filename, DoShow);
            app.filenameField.Value = filename;
            
             % インポート状態での主成分分析、軸描写
%             [app.importedfig, coeff, app.fixedPoint] = drawPCaxis3D(app.ptCloud.Location);
            [coeff, app.fixedPoint] = pca(app.ptCloud.Location);
            app.convexMatrix = coeff;
            % 新点群での主成分分析、軸修正、描写
            app.secondFig = scorePlot(app.fixedPoint);
            hold off;

        end

        % Button pushed function: PCATrimmerAppButton
        function PCATrimmerAppButtonPushed(app, event)
            Point = app.newPoint;
            up = app.trimmerrangeUp;
            down = app.trimmerrangeDown;
            switch app.trimAxes 
                case 'x'
                    Point = Point(Point(:,1) > down & up > Point(:,1), :);
                case 'y'
                    Point = Point(Point(:,2) > down & up > Point(:,2), :);
                case 'z'
                    Point = Point(Point(:,3) > down & up > Point(:,3), :);
            end
            [trimedfig, coeff, app.fixedPoint] = drawPCaxis3D(Point); 
            trimedfig = scorePlot(app.fixedPoint);
%             app.newPoint = app.fixedPoint;
            app.convMatrix = app.convexMatrix * coeff; 
        end

        % Value changed function: TrimerangeUp
        function TrimerangeUpValueChanged(app, event)
            value = app.TrimerangeUp.Value;
            app.trimerrangeUp = value;
        end

        % Value changed function: TrimerangeDown
        function TrimerangeDownValueChanged(app, event)
            value = app.TrimerangeDown.Value;
            app.trimerrangeDown = value;
        end

        % Selection changed function: TrimDirectionSelectButtonGroup
        function TrimDirectionSelectButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrimDirectionSelectButtonGroup.SelectedObject;
            app.trimAxes = selectedButton.Text;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            tipWidth = 40;
            sp = app.fixedPoint;
            disp(sp);
            sPoint= fixLegsDirection(pointCloud(sp), app.trimAxes, app.cutWidth);
            app.fixedPoint = fixHoofTipDirection(sPoint, app.trimAxes, tipWidth, 2);
            pcshowWithAxes(app.fixedPoint);
        end

        % Value changed function: TEditField
        function TEditFieldValueChanged(app, event)
            value = app.TEditField.Value;
            app.cutWidth = value;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.cutAxes = selectedButton.Text;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            sPoint = app.fixedPoint;
            axes = app.cutAxes;
            t = app.cutWidth;
            [XData, PointSetX] = sliceData(sPoint, 'x', t);
            [YData, PointSetY] = sliceData(sPoint, 'y', t);
            [ZData, PointSetZ] = sliceData(sPoint, 'z', t);
            switch axes
                case 'x'
                    forcusData = XData;
                    forcusPoints = PointSetX;
                case 'y'
                    forcusData = YData;
                    forcusPoints = PointSetY;
                case 'z'
                    forcusData = ZData;
                    forcusPoints = PointSetZ;
            end
            ax = app.UIAxes; 
            subplot(2, 1, 1, ax);
            plot(ax, forcusData(:,1), forcusData(:,2), 'b');
            xlabel("dt-x");ylabel("周囲長")
            subplot(2, 1, 2, ax);
            plot(ax, forcusData(:,1), forcusData(:,3), 'r');
            xlabel("dt-x");ylabel("Area");
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.PostureControlPanel.Enable = false;
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
            app.PostureControlPanel.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import ';
            app.filenameField = uieditfield(app.UIFigure, 'text');
            app.PostureControlPanel = uipanel(app.UIFigure);
            app.PostureControlPanel.Title = '姿勢制御';
            app.PCATrimmerAppButton = uibutton(app.PostureControlPanel, 'push');
            app.PCATrimmerAppButton.ButtonPushedFcn = createCallbackFcn(app, @PCATrimmerAppButtonPushed, true);
            app.PCATrimmerAppButton.Text = 'トリミング';
            app.Button = uibutton(app.PostureControlPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '肢、蹄方向の修正';
            app.TrimDirectionSelectButtonGroup = uibuttongroup(app.PostureControlPanel);
            app.TrimDirectionSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrimDirectionSelectButtonGroupSelectionChanged, true);
            app.TrimDirectionSelectButtonGroup.Title = 'メイン軸';
            app.xButton_2 = uiradiobutton(app.TrimDirectionSelectButtonGroup);
            app.xButton_2.Text = 'x';
            app.xButton_2.Value = true;
            app.yButton_2 = uiradiobutton(app.TrimDirectionSelectButtonGroup);
            app.yButton_2.Text = 'y';
            app.zButton_2 = uiradiobutton(app.TrimDirectionSelectButtonGroup);
            app.zButton_2.Text = 'z';
            app.Label = uilabel(app.PostureControlPanel);
            app.Label.Text = '上端';
            app.TrimerangeUp = uieditfield(app.PostureControlPanel, 'numeric');
            app.TrimerangeUp.ValueChangedFcn = createCallbackFcn(app, @TrimerangeUpValueChanged, true);
            app.Label_2 = uilabel(app.PostureControlPanel);
            app.Label_2.Text = '下端';
            app.TrimerangeDown = uieditfield(app.PostureControlPanel, 'numeric');
            app.TrimerangeDown.ValueChangedFcn = createCallbackFcn(app, @TrimerangeDownValueChanged, true);
            app.ResetPostureButton = uibutton(app.PostureControlPanel, 'push');
            app.ResetPostureButton.Text = 'Reset';
            app.OKButton = uibutton(app.PostureControlPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '周囲長計算';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '計算';
            app.TEditFieldLabel = uilabel(app.Panel);
            app.TEditFieldLabel.Text = 'カット幅T';
            app.TEditField = uieditfield(app.Panel, 'numeric');
            app.TEditField.ValueChangedFcn = createCallbackFcn(app, @TEditFieldValueChanged, true);
            app.TEditField.Value = 0.5;
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'メイン計算方向軸';
            app.xButton = uiradiobutton(app.ButtonGroup);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.yButton = uiradiobutton(app.ButtonGroup);
            app.yButton.Text = 'y';
            app.zButton = uiradiobutton(app.ButtonGroup);
            app.zButton.Text = 'z';
            app.ResetCalAutoButton = uibutton(app.Panel, 'push');
            app.ResetCalAutoButton.Text = 'Reset';
            app.ResetAllButton = uibutton(app.UIFigure, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end